classdef GrayScottController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        RunconsistencytestMenu         matlab.ui.container.Menu
        ExportstateconsistencyMenu     matlab.ui.container.Menu
        LoadmodelMenu                  matlab.ui.container.Menu
        SavemodelMenu                  matlab.ui.container.Menu
        SavevideoMenu                  matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ManualMenu                     matlab.ui.container.Menu
        AboutPPPMenu                   matlab.ui.container.Menu
        AbouttheAppMenu                matlab.ui.container.Menu
        GrayScottControllerPanel       matlab.ui.container.Panel
        VideoFormatDropDown            matlab.ui.control.DropDown
        VideoformatDropDownLabel       matlab.ui.control.Label
        VideoFPSEditField              matlab.ui.control.NumericEditField
        VideoFPSEditFieldLabel         matlab.ui.control.Label
        PlotFieldDropDown              matlab.ui.control.DropDown
        FieldLabel                     matlab.ui.control.Label
        SolverButtonGroup              matlab.ui.container.ButtonGroup
        FullRadioButton                matlab.ui.control.RadioButton
        StencilRadioButton             matlab.ui.control.RadioButton
        SparseRadioButton              matlab.ui.control.RadioButton
        Lamp                           matlab.ui.control.Lamp
        D_vEditField                   matlab.ui.control.NumericEditField
        D_vLabel                       matlab.ui.control.Label
        D_uEditField                   matlab.ui.control.NumericEditField
        D_uLabel                       matlab.ui.control.Label
        kSlider                        matlab.ui.control.Slider
        kSliderLabel                   matlab.ui.control.Label
        FSlider                        matlab.ui.control.Slider
        FSliderLabel                   matlab.ui.control.Label
        BoundaryconditionsButtonGroup  matlab.ui.container.ButtonGroup
        DirichletButton                matlab.ui.control.RadioButton
        NeumannButton                  matlab.ui.control.RadioButton
        PeriodicButton                 matlab.ui.control.RadioButton
        EquationLabel                  matlab.ui.control.Label
        dotVD_vnabla2VUV2FkVLabel      matlab.ui.control.Label
        dotUD_unabla2UUV2F1ULabel      matlab.ui.control.Label
        RecordButton                   matlab.ui.control.StateButton
        ResetButton                    matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        isRunning
        isClosing
        plotFig
        p
        grid 
        model
        plotAx
        plotImg
        isRecording
        videoWriter
        videoFile
        videoFPS
        videoFormat
        cons_u0
        cons_v0
        p0_F
        p0_k
        cons_snap
        lampGray  = [0.6 0.6 0.6];
        lampGreen = [0 1 0];
        lampRed   = [1 0 0];
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        function updateStatusBar(app)
            %STATUS BAR  Show essential run information in the blue bar.
        
            % BC type (all edges identical in GUI)
            bcType = "";
            try
                bcType = upper(string(app.p.BC.left.type));
            catch
                bcType = "";
            end
        
            % Recording flag
            if app.isRecording
                recTxt = "REC";
            else
                recTxt = "IDLE";
            end
        
            % Time/step from model if available
            if ~isempty(app.model)
                nTxt = sprintf("n=%d", app.model.n);
                tTxt = sprintf("t=%.3g", app.model.t);
            else
                nTxt = "n=?";
                tTxt = "t=?";
            end
        
            % Field shown
            fieldTxt = upper(string(app.p.plotField));
        
            % Parameters
            fkTxt = sprintf("F=%.5f | k=%.5f", app.p.F, app.p.k);
        
            % Compose
            if strlength(bcType) > 0
                app.Label.Text = sprintf("%s  |  %s  |  field=%s  |  BC=%s  |  %s  |  %s", ...
                    nTxt, tTxt, fieldTxt, bcType, fkTxt, recTxt);
            else
                app.Label.Text = sprintf("%s  |  %s  |  field=%s  |  %s  |  %s", ...
                    nTxt, tTxt, fieldTxt, fkTxt, recTxt);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appFile = mfilename("fullpath");
            if isempty(appFile)
                appFile = mfilename("fullpath");
            end
        
            appDir  = fileparts(appFile);
            rootDir = fileparts(appDir);
        
            addpath(genpath(fullfile(rootDir,"src")));
            addpath(genpath(fullfile(rootDir,"tests")));
        
            % --- UI / runtime flags ---
            app.isRunning   = false;
            app.isClosing   = false;
            app.isRecording = false;
        
            app.videoWriter = [];
            app.videoFile   = "";
            app.videoFPS    = 30;
            app.VideoFPSEditField.Value = app.videoFPS;
            app.videoFormat = string(app.VideoFormatDropDown.Value);
        
            app.PauseButton.Enable = "off";
        
            % --- Parameters (GUI policy: sparse solver fixed) ---
            app.p = defaultParams();
            app.p = finalizeParams(app.p);
            app.p.diffusionMode = "matrix";
        
            % Cache initial GUI defaults for Reset (F,k only)
            app.p0_F = app.p.F;
            app.p0_k = app.p.k;
        
            rng(app.p.seed, "twister");
        
            % --- Sync GUI widgets with parameters ---
            app.PlotFieldDropDown.Value = upper(app.p.plotField);          % 'U' or 'V'
            app.p.plotField = lower(string(app.PlotFieldDropDown.Value));  % "u"/"v"
        
            app.FSlider.Value = app.p.F;
            app.kSlider.Value = app.p.k;
        
            % GUI policy: Du, Dv visible but read-only
            app.D_uEditField.Value    = app.p.Du;
            app.D_vEditField.Value    = app.p.Dv;
            app.D_uEditField.Editable = "off";
            app.D_vEditField.Editable = "off";
        
            % --- Build model BEFORE status bar (so n,t are available) ---
            app.grid  = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);
        
            app.cons_u0   = app.model.u;
            app.cons_v0   = app.model.v;
            app.cons_snap = struct();
        
            % --- Lamp (idle at startup) ---
            app.Lamp.Color = app.lampGray;
        
            % --- Create / refresh plot window ---
            if isempty(app.plotFig) || ~isvalid(app.plotFig)
                app.plotFig = figure('Name','Gray-Scott Plot', 'NumberTitle','off');
            else
                figure(app.plotFig);
            end
        
            [U, V] = app.model.getFields2D();
        
            if app.p.plotField == "v"
                Z = V;
                fieldChar = "V";
            else
                Z = U;
                fieldChar = "U";
            end
        
            figure(app.plotFig);
            clf(app.plotFig);
        
            app.plotAx  = axes(app.plotFig);
            app.plotImg = imagesc(app.plotAx, Z);
        
            axis(app.plotAx, 'image');
            set(app.plotAx, 'YDir', 'normal');
            colorbar(app.plotAx);
            title(app.plotAx, "Initial Field " + fieldChar);
        
            % --- Status bar AFTER model is ready ---
            app.updateStatusBar();
        
            % Hide/disable solver selection in GUI (script mode only)
            app.SolverButtonGroup.Enable  = "off";
            app.SolverButtonGroup.Visible = "off";

            % GUI policy: hide unfinished menu items (keep GUI simple)
            app.LoadmodelMenu.Visible  = "off";
            app.SavemodelMenu.Visible  = "off";
            app.SavevideoMenu.Visible  = "off";
            app.ExitMenu.Visible       = "off";
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.isRunning = true;
        
            app.RunButton.Enable   = "off";
            app.PauseButton.Enable = "on";

            % Consistency snapshot settings (matches non-GUI reference logic)
            Nt_exact = app.p.T / app.p.dt;
            assert(abs(Nt_exact - round(Nt_exact)) < 1e-12, "T must be divisible by dt.");
            nStepsTarget = round(Nt_exact);
            kSnapTarget  = round(nStepsTarget/2);

            % Open video writer if recording is enabled
            if app.isRecording
                try
                    fmt = string(app.VideoFormatDropDown.Value);

                    if fmt == "AVI (Motion JPEG)"
                        app.videoWriter = VideoWriter(app.videoFile, "Motion JPEG AVI");
                    else
                        app.videoWriter = VideoWriter(app.videoFile, "MPEG-4");
                    end
                    
                    app.videoWriter.FrameRate = app.videoFPS;
                    open(app.videoWriter);
                    % Write one initial frame immediately (so video is never empty)
                    vw0 = app.videoWriter;
                    if isa(vw0, "VideoWriter")
                        try
                            fr0 = getframe(app.plotFig);
                            writeVideo(vw0, fr0);
                        catch
                        end
                    end
                catch ME
                    % Fail safe: disable recording and notify user
                    app.isRecording = false;
                    app.RecordButton.Value = false;
                    app.videoWriter = [];
                    app.Lamp.Color = app.lampGray;
                    app.Label.Text = "Recording failed to start: " + string(ME.message);
                end
            end
        
            % Safety: if plot figure was closed, recreate it
            if isempty(app.plotFig) || ~isvalid(app.plotFig)
                app.plotFig = figure('Name','Gray–Scott Plot', 'NumberTitle','off');
                app.plotAx  = axes(app.plotFig);
                [U, ~] = app.model.getFields2D();
                app.plotImg = imagesc(app.plotAx, U);
                axis(app.plotAx, 'image');
                set(app.plotAx, 'YDir', 'normal');
                colorbar(app.plotAx);
            end
        
            % Main loop (no timer): runs until Pause or app closed
            while app.isRunning && ~app.isClosing
        
                % Make sure model uses latest F,k (runtime tuning)
                app.model.p.F = app.p.F;
                app.model.p.k = app.p.k;
        
                % Step a small batch per UI refresh
                nBatch = 20;
                info = struct();
                for i = 1:nBatch
                    info = app.model.step();
                    if isfield(info,'hasNaNInf') && info.hasNaNInf
                        break;
                    end
                end

                % Capture snapshot once at kSnapTarget
                if isempty(app.cons_snap) || ~isfield(app.cons_snap, "kSnap")
                    if app.model.n >= kSnapTarget
                        app.cons_snap.us   = app.model.u;
                        app.cons_snap.vs   = app.model.v;
                        app.cons_snap.kSnap = kSnapTarget;
                        app.cons_snap.nStepsTarget = nStepsTarget;
                    end
                end
                % Plot update every plotEvery steps
                if app.p.plotEvery > 0 && mod(app.model.n, app.p.plotEvery) == 0
                
                    % Recreate plot window if it was closed during run
                    if isempty(app.plotFig) || ~isvalid(app.plotFig) || isempty(app.plotAx) || ~isvalid(app.plotAx) || ...
                       isempty(app.plotImg) || ~isvalid(app.plotImg)
                
                        % If recording was active, stop it (plot was interrupted)
                        if app.isRecording
                            app.isRecording = false;
                            app.RecordButton.Value = false;
                        
                            vw = app.videoWriter;
                            if isa(vw, "VideoWriter")
                                try
                                    close(vw);
                                catch
                                end
                            end
                            app.videoWriter = [];
                        end

                        app.plotFig = figure('Name','Gray–Scott Plot', 'NumberTitle','off');
                        app.plotAx  = axes(app.plotFig);
                
                        [U0, V0] = app.model.getFields2D();
                        if app.p.plotField == "v"
                            Z0 = V0;
                        else
                            Z0 = U0;
                        end
                
                        app.plotImg = imagesc(app.plotAx, Z0);
                        axis(app.plotAx, 'image');
                        set(app.plotAx, 'YDir', 'normal');
                        colorbar(app.plotAx);
                    end
                
                    [U, V] = app.model.getFields2D();
                
                    if app.p.plotField == "v"
                        Z = V;
                        fieldChar = "V";
                    else
                        Z = U;
                        fieldChar = "U";
                    end
                
                    if ~isempty(app.plotImg) && isvalid(app.plotImg)
                        app.plotImg.CData = Z;
                        title(app.plotAx, sprintf("%s (n=%d, t=%.3g)", fieldChar, app.model.n, app.model.t));
                
                        % Write video frame (race-safe: Pause/Reset/Close)
                        if app.isRecording
                            vw = app.videoWriter;   % snapshot
                            if isa(vw, "VideoWriter")
                                try
                                    frame = getframe(app.plotFig);   % may fail if plotFig is closing
                                    writeVideo(vw, frame);           % may fail if writer was closed
                                catch
                                    % Stop recording quietly (writer/figure likely closed)
                                    app.isRecording = false;
                                    app.RecordButton.Value = false;
                
                                    try
                                        close(vw);
                                    catch
                                    end
                                    app.videoWriter = [];
                                end
                            end
                        end
                    end
                end
        
                % Stop on NaN/Inf
                if isfield(info,'hasNaNInf') && info.hasNaNInf
                    app.isRunning = false;
        
                    app.Lamp.Color = app.lampRed;
                    app.Label.Text = "Stopped: NaN/Inf detected.";
        
                    app.RunButton.Enable   = "on";
                    app.PauseButton.Enable = "off";

                    % Finalize recording on numerical failure
                    if app.isRecording && isa(app.videoWriter, "VideoWriter")
                        app.isRecording = false;
                        app.RecordButton.Value = false;
                    
                        try
                            close(app.videoWriter);
                        catch
                        end
                        app.videoWriter = [];
                    end
                    break;
                end
     
                % Allow UI callbacks to run (Pause/Reset/close)
                drawnow limitrate;
                app.updateStatusBar();
            end
        
            % Ensure UI is consistent after exiting loop
            if isvalid(app.UIFigure)
                app.RunButton.Enable   = "on";
                app.PauseButton.Enable = "off";
            end

        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.isRunning = false;
            app.RunButton.Enable = "on";
            app.PauseButton.Enable = "off";

            % Finalize recording on pause
            if app.isRecording && isa(app.videoWriter, "VideoWriter")
                app.isRecording = false;
                app.RecordButton.Value = false;
            
                try
                    close(app.videoWriter);
                catch
                end
                app.videoWriter = [];
            end
            app.Lamp.Color = app.lampGray;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.isRunning = false;
            app.RunButton.Enable = "on";
            app.PauseButton.Enable = "off";

            % Finalize recording on reset
            if app.isRecording && isa(app.videoWriter, "VideoWriter")
                app.isRecording = false;
                app.RecordButton.Value = false;
            
                try
                    close(app.videoWriter);
                catch
                end
                app.videoWriter = [];
            end

            % Restore F and k to initial GUI defaults
            app.p.F = app.p0_F;
            app.p.k = app.p0_k;
            
            % Keep GUI widgets consistent with the restored defaults
            app.FSlider.Value = app.p.F;
            app.kSlider.Value = app.p.k;

            app.p = finalizeParams(app.p);
            rng(app.p.seed, "twister");

            app.p.diffusionMode = "matrix";

            % Recreate model using current parameters
            app.grid  = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);

            app.cons_u0 = app.model.u;
            app.cons_v0 = app.model.v;
            app.cons_snap = struct();
            
            % Sync model params (in case constructor copies p)
            app.model.p.F = app.p.F;
            app.model.p.k = app.p.k;
            
            app.Lamp.Color = app.lampGray;

            % Redraw initial conidtion
            [U, V] = app.model.getFields2D();

            if app.p.plotField == "v"
                Z = V;
                fieldChar = "V";
            else
                Z = U;
                fieldChar = "U";
            end
            
            if ~isempty(app.plotImg) && isvalid(app.plotImg)
                app.plotImg.CData = Z;
                title(app.plotAx, sprintf("Initial %s (Reset) (n=%d, t=%.3g)", fieldChar, app.model.n, app.model.t));
                drawnow;
            end

            % Keep diffusion coefficients visible (GUI read-only)
            app.D_uEditField.Value = app.p.Du;
            app.D_vEditField.Value = app.p.Dv;
            app.D_uEditField.Editable = "off";
            app.D_vEditField.Editable = "off";
            
            app.updateStatusBar();

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isClosing = true;
            app.isRunning = false;
            % Finalize recording on app close
            if app.isRecording
                app.isRecording = false;
                app.RecordButton.Value = false;
            
                vw = app.videoWriter;
                if isa(vw, "VideoWriter")
                    try
                        close(vw);
                    catch
                    end
                end
                app.videoWriter = [];
            end
            try
                if ~isempty(app.plotFig) && isvalid(app.plotFig)
                    close(app.plotFig);
                end
            catch
            end
            
            % Hide UI immediately, but do not delete the app while loop is active
            try
                app.UIFigure.Visible = "off";
            catch
            end
            
        end

        % Value changed function: FSlider
        function FSliderValueChanged(app, event)
            app.p.F = app.FSlider.Value;
            if ~isempty(app.model)
                app.model.p.F = app.p.F;
            end

            bcType = string(app.p.BC.left.type);
            
            app.Label.Text = sprintf("F = %.5f   |   k = %.5f   |   field = %s   |   mode = %s   |   BC = %s", ...
                app.p.F, app.p.k, upper(app.p.plotField), string(app.p.diffusionMode), upper(char(bcType)));

        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            app.p.k = app.kSlider.Value;
            if ~isempty(app.model)
                app.model.p.k = app.p.k;
            end

            bcType = string(app.p.BC.left.type);

            app.Label.Text = sprintf("F = %.5f   |   k = %.5f   |   field = %s   |   mode = %s   |   BC = %s", ...
                app.p.F, app.p.k, upper(app.p.plotField), string(app.p.diffusionMode), upper(char(bcType)));

        end

        % Selection changed function: SolverButtonGroup
        function SolverButtonGroupSelectionChanged(app, event)
            % GUI policy: solver selection is disabled (script mode only).
            app.p.diffusionMode = "matrix";
            if ~isempty(app.model)
                app.model.p.diffusionMode = "matrix";
            end
        end

        % Value changed function: PlotFieldDropDown
        function PlotFieldDropDownValueChanged(app, event)
        
            % Store selected plot field 
            app.p.plotField = lower(string(app.PlotFieldDropDown.Value)); % "u" or "v"
        
            % Keep status text consistent 
            bcType = string(app.p.BC.left.type);

            app.Label.Text = sprintf("F = %.5f   |   k = %.5f   |   field = %s   |   mode = %s   |   BC = %s", ...
                app.p.F, app.p.k, upper(app.p.plotField), string(app.p.diffusionMode), upper(char(bcType)));

        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            app.isRecording = logical(app.RecordButton.Value);
        
            if app.isRecording
                % Default filename with date+time (no dialog)
                outDir = fullfile(pwd, "results");
                if ~exist(outDir, "dir")
                    mkdir(outDir);
                end
                
                stamp = char(datetime("now","Format","yyyyMMdd_HHmmss"));
                
                fmt = string(app.VideoFormatDropDown.Value);
                if fmt == "AVI (Motion JPEG)"
                    ext = ".avi";
                else
                    ext = ".mp4";
                end
                
                app.videoFile = fullfile(outDir, "video_" + string(stamp) + ext);

            else
                app.videoFile = "";
            end

            if app.isRecording
                app.Lamp.Color = app.lampGreen;  % recording active
            else
                app.Lamp.Color = app.lampGray;   % idle
            end

            if app.isRecording
                app.Label.Text = sprintf("F = %.5f   |   k = %.5f   |   field = %s   |   mode = %s   |   REC", ...
                    app.p.F, app.p.k, upper(app.p.plotField), string(app.p.diffusionMode));
            else
                app.Label.Text = sprintf("F = %.5f   |   k = %.5f   |   field = %s   |   mode = %s", ...
                    app.p.F, app.p.k, upper(app.p.plotField), string(app.p.diffusionMode));
            end
        end

        % Value changed function: VideoFPSEditField
        function VideoFPSEditFieldValueChanged(app, event)
            app.videoFPS = app.VideoFPSEditField.Value;
        
            % If recording is already open, update the writer too (safe try)
            vw = app.videoWriter;
            if isa(vw, "VideoWriter")
                try
                    vw.FrameRate = app.videoFPS;
                catch
                end
            end            
        end

        % Value changed function: VideoFormatDropDown
        function VideoFormatDropDownValueChanged(app, event)
            app.videoFormat = string(app.VideoFormatDropDown.Value);
        end

        % Selection changed function: BoundaryconditionsButtonGroup
        function BoundaryconditionsButtonGroupSelectionChanged(app, event)
                % Determine selected BC type (always define bcType)
                bcType = "periodic";  % default (prevents undefined-variable errors)
                selected = app.BoundaryconditionsButtonGroup.SelectedObject;
            
                if selected == app.PeriodicButton
                    bcType = "periodic";
                elseif selected == app.NeumannButton
                    bcType = "neumann";
                elseif selected == app.DirichletButton
                    bcType = "dirichlet";
                end
            
                app.p.diffusionMode = "matrix";

                % Apply chosen type to all sides (GUI policy: all edges share same BC)
                sides = ["left","right","bottom","top"];
                for s = 1:numel(sides)
                    side = sides(s);
                    app.p.BC.(side).type = bcType;
            
                    % GUI uses constant homogeneous BC values (advanced options in script mode)
                    if bcType == "dirichlet"
                        app.p.BC.(side).dirichletMode = "constant";
                        app.p.BC.(side).dirichletValue.u = 0.0;
                        app.p.BC.(side).dirichletValue.v = 0.0;
            
                    elseif bcType == "neumann"
                        app.p.BC.(side).neumannMode = "constant";
                        app.p.BC.(side).neumannValue.u = 0.0;
                        app.p.BC.(side).neumannValue.v = 0.0;
                    end
                end
            
                % Policy A: boundary-condition changes require a reset
                app.isRunning = false;
                app.RunButton.Enable   = "on";
                app.PauseButton.Enable = "off";
            
                % Finalize recording (same behavior as Reset/Pause)
                if app.isRecording && isa(app.videoWriter, "VideoWriter")
                    app.isRecording = false;
                    app.RecordButton.Value = false;
                    try
                        close(app.videoWriter);
                    catch
                    end
                    app.videoWriter = [];
                end
            
                % Rebuild model using current GUI parameters
                app.p = finalizeParams(app.p);
                rng(app.p.seed, "twister");
            
                app.grid  = buildGrid(app.p);
                app.model = GrayScottModel(app.p, app.grid);
            
                app.cons_u0 = app.model.u;
                app.cons_v0 = app.model.v;
                app.cons_snap = struct();
            
                app.Lamp.Color = app.lampGray;
            
                % Redraw initial state
                [U, V] = app.model.getFields2D();
                if app.p.plotField == "v"
                    Z = V;
                    fieldChar = "V";
                else
                    Z = U;
                    fieldChar = "U";
                end
            
                if ~isempty(app.plotImg) && isvalid(app.plotImg)
                    app.plotImg.CData = Z;
                    title(app.plotAx, sprintf("Initial %s (BC reset) (n=%d, t=%.3g)", ...
                        fieldChar, app.model.n, app.model.t));
                    drawnow;
                end

            app.updateStatusBar();
        end

        % Menu selected function: ExportstateconsistencyMenu
        function ExportstateconsistencyMenuSelected(app, event)
            % Locate project root robustly (independent of Editor active file)
            thisFile = mfilename("fullpath");   % .../scripts/GrayScottController
            appDir   = fileparts(thisFile);     % .../scripts
            rootDir  = fileparts(appDir);       % project root
            
            outDir = fullfile(rootDir, "results", "consistency");
            if ~exist(outDir, "dir"), mkdir(outDir); end
            
            % Match non-GUI parameter pipeline
            p    = finalizeParams(app.p);
            seed = p.seed;
            
            % Consistency settings (same logic as non-GUI reference)
            Nt_exact = p.T / p.dt;
            assert(abs(Nt_exact - round(Nt_exact)) < 1e-12, "T must be divisible by dt.");
            settings.nSteps = round(Nt_exact);
            settings.kSnap  = round(settings.nSteps/2);
            
            % Pack snapshots
            snap = struct();
            
            % Initial state (must be cached on startup/reset; if not present, leave empty)
            if isprop(app, "cons_u0") && ~isempty(app.cons_u0)
                snap.u0 = app.cons_u0;
                snap.v0 = app.cons_v0;
            else
                snap.u0 = [];
                snap.v0 = [];
            end
            
            % Intermediate snapshot (captured during run when reaching kSnap)
            if isprop(app, "cons_snap") && ~isempty(app.cons_snap) && isfield(app.cons_snap, "kSnap")
                snap.us   = app.cons_snap.us;
                snap.vs   = app.cons_snap.vs;
                snap.kSnap = app.cons_snap.kSnap;
            else
                snap.us   = [];
                snap.vs   = [];
                snap.kSnap = settings.kSnap;
            end
            
            % Current state (treated as "final" of whatever run length is currently reached)
            snap.uf = app.model.u;
            snap.vf = app.model.v;
            snap.n  = app.model.n;
            snap.t  = app.model.t;
            
            timestamp = datestr(now, "yyyy-mm-dd_HH-MM-SS");
            guiFile = fullfile(outDir, "gui_dump_" + string(timestamp) + ".mat");
            
            save(guiFile, "p", "seed", "settings", "snap", "-v7.3");
            
            uialert(app.UIFigure, "Saved: " + string(guiFile), "Consistency export");
        end

        % Menu selected function: RunconsistencytestMenu
        function RunconsistencytestGUIvsnonGUIMenuSelected(app, event)
        %RUNCONSISTENCYTEST...  Run GUI vs non-GUI consistency test and write artifacts.
        %
        % This is a verification utility. It does not affect normal GUI usage.
        % It runs a deterministic non-GUI reference and a deterministic GUI run
        % for a fixed number of steps, then compares and writes:
        %   - figures/verification/gui_consistency.png
        %   - results/consistency/gui_consistency_table.csv
        
        % ---- Choose test length (edit here if you want 10000, 20000, ...) ----
        nStepsTarget = 10000;
        
        % ---- Project root from this class file (robust) ----
        thisFile = mfilename("fullpath");   % .../scripts/GrayScottController
        appDir   = fileparts(thisFile);     % .../scripts
        rootDir  = fileparts(appDir);       % project root
        
        addpath(genpath(fullfile(rootDir, "src")));
        addpath(genpath(fullfile(rootDir, "tests")));
                
        % ---- Build deterministic parameters (same pipeline as scripts/run_model_class.m) ----
        p = defaultParams();
        p = finalizeParams(p);
        rng(p.seed, "twister");
        
        % Force numerics-only in test (no plotting, no PNG spam)
        p.plotEvery    = 0;
        p.savePngEvery = 0;
        
        % Ensure nStepsTarget does not exceed configured T/dt unless we extend T.
        Nt_exact = p.T / p.dt;
        assert(abs(Nt_exact - round(Nt_exact)) < 1e-12, "T must be divisible by dt.");
        nStepsMax = round(Nt_exact);
        
        if nStepsTarget > nStepsMax
            % Extend final time to support longer test without changing dt
            p.T = nStepsTarget * p.dt;
        end
        
        % ---- Non-GUI reference dump (uses same model/core) ----
        refFile = run_nongui_reference_dump_for_steps(rootDir, p, nStepsTarget);
        rng(p.seed, "twister");
        
        % ---- GUI run inside the app (no normal Run loop) ----
        % Reset model deterministically from p
        app.p = p;
        app.grid  = buildGrid(app.p);
        app.model = GrayScottModel(app.p, app.grid);
        
        % Cache initial
        app.cons_u0 = app.model.u;
        app.cons_v0 = app.model.v;
        
        % Snapshot at midpoint
        kSnap = round(nStepsTarget/2);
        app.cons_snap = struct();
        
        for n = 1:nStepsTarget
            info = app.model.step();
            if isfield(info,"hasNaNInf") && info.hasNaNInf
                error("NaN/Inf detected during consistency test at step %d.", app.model.n);
            end
            if n == kSnap
                app.cons_snap.us = app.model.u;
                app.cons_snap.vs = app.model.v;
                app.cons_snap.kSnap = kSnap;
            end
        end
        
        % ---- Export GUI dump (in results/consistency) ----
        outDir = fullfile(rootDir, "results", "consistency");
        if ~exist(outDir, "dir"), mkdir(outDir); end
        timestamp = datestr(now, "yyyy-mm-dd_HH-MM-SS");
        guiFile = fullfile(outDir, "gui_dump_" + string(timestamp) + ".mat");
        
        settings.nSteps = nStepsTarget;
        settings.kSnap  = kSnap;
        
        snap = struct();
        snap.u0 = app.cons_u0;
        snap.v0 = app.cons_v0;
        snap.us = app.cons_snap.us;
        snap.vs = app.cons_snap.vs;
        snap.kSnap = kSnap;
        snap.uf = app.model.u;
        snap.vf = app.model.v;
        snap.n = app.model.n;
        snap.nSteps = nStepsTarget;
        
        seed = p.seed; %#ok<NASGU>
        save(guiFile, "p", "seed", "settings", "snap", "-v7.3");
        
        % ---- Compare and generate artifacts ----
        compare_gui_nongui(refFile, guiFile);
        
        uialert(app.UIFigure, ...
            "Consistency test finished." + newline + ...
            "Saved GUI dump: " + string(guiFile), ...
            "Consistency test");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RunconsistencytestMenu = uimenu(app.FileMenu);
            app.RunconsistencytestMenu.MenuSelectedFcn = createCallbackFcn(app, @RunconsistencytestGUIvsnonGUIMenuSelected, true);
            app.RunconsistencytestMenu.Text = 'Run consistency test';
            app.ExportstateconsistencyMenu = uimenu(app.FileMenu);
            app.ExportstateconsistencyMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportstateconsistencyMenuSelected, true);
            app.ExportstateconsistencyMenu.Separator = 'on';
            app.ExportstateconsistencyMenu.Text = 'Export state (consistency)';
            app.ExportstateconsistencyMenu.Tag = 'ExportConsistencyMenu';
            app.LoadmodelMenu = uimenu(app.FileMenu);
            app.LoadmodelMenu.Text = 'Load model';
            app.SavemodelMenu = uimenu(app.FileMenu);
            app.SavemodelMenu.Text = 'Save model';
            app.SavevideoMenu = uimenu(app.FileMenu);
            app.SavevideoMenu.Separator = 'on';
            app.SavevideoMenu.Text = 'Save video';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.Text = 'Manual';
            app.AboutPPPMenu = uimenu(app.HelpMenu);
            app.AboutPPPMenu.Text = 'About PPP';
            app.AbouttheAppMenu = uimenu(app.HelpMenu);
            app.AbouttheAppMenu.Text = 'About the App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.GrayScottControllerPanel = uipanel(app.UIFigure);
            app.GrayScottControllerPanel.Title = 'Gray-Scott-Controller';
            app.RunButton = uibutton(app.GrayScottControllerPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PauseButton = uibutton(app.GrayScottControllerPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.GrayScottControllerPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RecordButton = uibutton(app.GrayScottControllerPanel, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.dotUD_unabla2UUV2F1ULabel = uilabel(app.GrayScottControllerPanel);
            app.dotUD_unabla2UUV2F1ULabel.Interpreter = 'latex';
            app.dotUD_unabla2UUV2F1ULabel.Text = '$\dot U = D_u \nabla ^2 U - UV^2  + F(1-U)$';
            app.dotVD_vnabla2VUV2FkVLabel = uilabel(app.GrayScottControllerPanel);
            app.dotVD_vnabla2VUV2FkVLabel.Interpreter = 'latex';
            app.dotVD_vnabla2VUV2FkVLabel.Text = '$\dot V = D_v \nabla ^2 V + UV^2  - (F+k)V$';
            app.EquationLabel = uilabel(app.GrayScottControllerPanel);
            app.EquationLabel.Text = 'Equation';
            app.BoundaryconditionsButtonGroup = uibuttongroup(app.GrayScottControllerPanel);
            app.BoundaryconditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundaryconditionsButtonGroupSelectionChanged, true);
            app.BoundaryconditionsButtonGroup.Title = 'Boundary conditions';
            app.PeriodicButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.PeriodicButton.Text = 'Periodic';
            app.PeriodicButton.Value = true;
            app.NeumannButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.NeumannButton.Text = 'Neumann';
            app.DirichletButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.DirichletButton.Text = 'Dirichlet';
            app.FSliderLabel = uilabel(app.GrayScottControllerPanel);
            app.FSliderLabel.Text = 'F';
            app.FSlider = uislider(app.GrayScottControllerPanel);
            app.FSlider.Limits = [0 0.1];
            app.FSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.FSlider.ValueChangedFcn = createCallbackFcn(app, @FSliderValueChanged, true);
            app.FSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.kSliderLabel = uilabel(app.GrayScottControllerPanel);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.GrayScottControllerPanel);
            app.kSlider.Limits = [0 0.1];
            app.kSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.D_uLabel = uilabel(app.GrayScottControllerPanel);
            app.D_uLabel.Interpreter = 'latex';
            app.D_uLabel.Text = '$D_u$';
            app.D_uEditField = uieditfield(app.GrayScottControllerPanel, 'numeric');
            app.D_uEditField.Value = 2e-05;
            app.D_vLabel = uilabel(app.GrayScottControllerPanel);
            app.D_vLabel.Interpreter = 'latex';
            app.D_vLabel.Text = '$D_v$';
            app.D_vEditField = uieditfield(app.GrayScottControllerPanel, 'numeric');
            app.D_vEditField.Value = 1e-05;
            app.Lamp = uilamp(app.GrayScottControllerPanel);
            app.SolverButtonGroup = uibuttongroup(app.GrayScottControllerPanel);
            app.SolverButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolverButtonGroupSelectionChanged, true);
            app.SolverButtonGroup.Title = 'Solver';
            app.SparseRadioButton = uiradiobutton(app.SolverButtonGroup);
            app.SparseRadioButton.Text = 'Sparse (matrix)';
            app.SparseRadioButton.Value = true;
            app.StencilRadioButton = uiradiobutton(app.SolverButtonGroup);
            app.StencilRadioButton.Text = 'Stencil';
            app.FullRadioButton = uiradiobutton(app.SolverButtonGroup);
            app.FullRadioButton.Text = 'Full (dense)';
            app.FieldLabel = uilabel(app.GrayScottControllerPanel);
            app.FieldLabel.Text = 'Field';
            app.PlotFieldDropDown = uidropdown(app.GrayScottControllerPanel);
            app.PlotFieldDropDown.Items = {'U', 'V'};
            app.PlotFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFieldDropDownValueChanged, true);
            app.PlotFieldDropDown.Value = 'U';
            app.VideoFPSEditFieldLabel = uilabel(app.GrayScottControllerPanel);
            app.VideoFPSEditFieldLabel.Text = 'Video FPS';
            app.VideoFPSEditField = uieditfield(app.GrayScottControllerPanel, 'numeric');
            app.VideoFPSEditField.Limits = [1 60];
            app.VideoFPSEditField.ValueChangedFcn = createCallbackFcn(app, @VideoFPSEditFieldValueChanged, true);
            app.VideoFPSEditField.Value = 30;
            app.VideoformatDropDownLabel = uilabel(app.GrayScottControllerPanel);
            app.VideoformatDropDownLabel.Text = 'Video format';
            app.VideoFormatDropDown = uidropdown(app.GrayScottControllerPanel);
            app.VideoFormatDropDown.Items = {'MP4 (H.264)', 'AVI (Motion JPEG)'};
            app.VideoFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoFormatDropDownValueChanged, true);
            app.VideoFormatDropDown.Value = 'MP4 (H.264)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrayScottController

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end